classdef view_bottom_camera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        TMagentaSliderLabel     matlab.ui.control.Label
        TMagentaSlider          matlab.ui.control.Slider
        TGreenSliderLabel       matlab.ui.control.Label
        TGreenSlider            matlab.ui.control.Slider
        BaseFolderEditField     matlab.ui.control.EditField
        LoadImagesButton        matlab.ui.control.Button
        ResetImLimitsButton     matlab.ui.control.Button
        SaveCentroidsButton     matlab.ui.control.Button
        RealignCentroidsButton  matlab.ui.control.Button
        PlotDiffImButton        matlab.ui.control.Button
        FlipUDCheckBox          matlab.ui.control.CheckBox
        FlipLRCheckBox          matlab.ui.control.CheckBox
        TGreenEditFieldLabel    matlab.ui.control.Label
        TGreenEditField         matlab.ui.control.NumericEditField
        TMagentaEditFieldLabel  matlab.ui.control.Label
        TMagentaEditField       matlab.ui.control.NumericEditField
        analyzesitesButton      matlab.ui.control.Button
        RefreshImagesButton     matlab.ui.control.Button
        autorefreshCheckBox     matlab.ui.control.CheckBox
        EqGreenTimeButton       matlab.ui.control.Button
        ShowVideoButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        FullImages % Images loaded from bottom camera
        CropImages % Cropped image data around cache site centers
        cacheLocBot % Centroids of cache locations in bottom camera
        lBorder = 8 % half-width of window used for cropping around cache site centroids
        n_caches = 84 % number of cache sites
        refreshTimer % timer object for auto-image refresh/re-loading
        refreshTimerPeriod = 15 % Period (in seconds) at which timer polls folder to detect new images
    end
    
    methods (Access = private)
        
        function cropSites(app)
            % account for feeders
            max_dummy = app.n_caches + 16; 
            int = sqrt(max_dummy);
            dummySites = [1, 2, int-1, int,...
                            int+1, int+2, 2*int-1, 2*int,...
                            max_dummy-2*int+1, max_dummy-2*int+2, max_dummy-int-1, max_dummy-int,...
                            max_dummy-int+1, max_dummy-int+2, max_dummy-1, max_dummy];
            trueSites = setdiff(1:max_dummy, dummySites);
            cacheLocBot_distort = app.cacheLocBot;
            fullImages = app.FullImages;
            
            % Crop around each site
            winSize = app.lBorder*2+1;
            cacheCrop = zeros(winSize*int, winSize*int, size(fullImages,3), 'uint8');
            for i=1:144
                xInd = floor((i-1)/int)*winSize + (1:winSize);
                yInd = mod((i-1),int)*winSize + (1:winSize);
                if ismember(i,trueSites)
                    thisLoc = round(cacheLocBot_distort(trueSites==i,:));
                    cacheIndX = thisLoc(1) + (-app.lBorder:app.lBorder);
                    cacheIndY = thisLoc(2) + (-app.lBorder:app.lBorder);
                    cacheCrop(yInd,xInd,:) = fullImages(cacheIndY, cacheIndX, :);
                end
            end
            app.CropImages = cacheCrop;
        end
        
        function AutodetectFolder(app)
            baseFolder = app.BaseFolderEditField.Value;
            fldr = dir(baseFolder);
            fldr = fldr(3:end); %get rid of root and current folder options
            fldr = fldr([fldr.isdir]);
            [~,i] = max([fldr.datenum]);
            fldr = fullfile(fldr(i).folder,fldr(i).name);
            app.BaseFolderEditField.Value = fldr;
        end
        
        function updateImage(app)
            t1 = app.TGreenSlider.Value+1;
            t2 = app.TMagentaSlider.Value+1;
            tmp = imfuse(app.CropImages(:,:,t1),app.CropImages(:,:,t2));
            if app.FlipUDCheckBox.Value
                tmp = flipud(tmp);
            end
            if app.FlipLRCheckBox.Value
                tmp = fliplr(tmp);
            end
            imshow(tmp,'Parent',app.UIAxes,'XData',[0,1],'YData',[0,1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sessionPath)
            if ~exist("sessionPath", 'var') || isempty(sessionPath)
                app.AutodetectFolder;
            else
                app.BaseFolderEditField.Value = sessionPath;
            end
            app.LoadImagesButtonPushed;
            app.refreshTimer = timer('ExecutionMode','fixedRate','Period',app.refreshTimerPeriod,'StartDelay',app.refreshTimerPeriod,...
                'TimerFcn',@(x,y) app.RefreshImagesButtonPushed([]));
            if app.autorefreshCheckBox.Value
                start(app.refreshTimer),
            end
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            baseFolder = app.BaseFolderEditField.Value;
            try
                tmp = load(fullfile(baseFolder,'cacheLoc_botCam.mat'));
                disp('Centroids Loaded from Session-Specific Alignment'),
            catch
                disp('Loading Centroids from Default Alignment'),
                tmp = load('C:\Users\arono\Documents\code\il_rig_control\arena_alignment\cacheLoc_botCam.mat');
            end
            app.cacheLocBot = tmp.cacheLocBot_distort;

            allImages = dir(fullfile(baseFolder,'botImages','*.tiff'));
            [~,imOrder] = sort([allImages.datenum],'ascend');
            allImages = allImages(imOrder);
            nImages = length(allImages);
            imInfo = imfinfo(fullfile(allImages(1).folder,allImages(1).name));
            w = imInfo.Width;
            h = imInfo.Height;
            app.FullImages = zeros(h,w,nImages,'uint8');
            for i=1:nImages
                thisFile = fullfile(allImages(i).folder,allImages(i).name);
                app.FullImages(:,:,i) = imread(thisFile);
            end
            app.cropSites;
            app.updateImage;
            
            % set up sliders
            if nImages > 1
                app.TGreenSlider.Limits = [0, nImages-1];
                app.TGreenSlider.MajorTicks = 0:5:nImages-1;
                app.TGreenSlider.MinorTicks = 0:nImages-1;
                app.TMagentaSlider.Limits = [0, nImages-1];
                app.TMagentaSlider.MajorTicks = 0:5:nImages-1;
                app.TMagentaSlider.MinorTicks = 0:nImages-1;
            end
        end

        % Value changed function: TGreenSlider
        function TGreenSliderValueChanged(app, event)
            app.TGreenSlider.Value = round(app.TGreenSlider.Value);
            app.TGreenEditField.Value = app.TGreenSlider.Value;
            app.updateImage;
        end

        % Value changed function: TMagentaSlider
        function TMagentaSliderValueChanged(app, event)
            app.TMagentaSlider.Value = round(app.TMagentaSlider.Value);
            app.TMagentaEditField.Value = app.TMagentaSlider.Value;
            app.updateImage;
        end

        % Button pushed function: ResetImLimitsButton
        function ResetImLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [0, 1];
            app.UIAxes.YLim = [0, 1];
        end

        % Button pushed function: SaveCentroidsButton
        function SaveCentroidsButtonPushed(app, event)
            baseFolder = app.BaseFolderEditField.Value;
            cacheLocBot_distort = app.cacheLocBot;
            disp('Centroids Saved to Session-Specific Alignment'),
            save(fullfile(baseFolder,'cacheLoc_botCam.mat'),'cacheLocBot_distort'),
        end

        % Button pushed function: PlotDiffImButton
        function PlotDiffImButtonPushed(app, event)
            t1 = app.TGreenSlider.Value+1;
            t2 = app.TMagentaSlider.Value+1;
            tmp = double(app.CropImages(:,:,t2))/255 - double(app.CropImages(:,:,t1))/255 + 0.5;
            diffIm = adapthisteq(tmp, 'NumTiles',[4,4],'ClipLimit',1e-3);
            if app.FlipUDCheckBox.Value
                diffIm = flipud(diffIm);
            end
            if app.FlipLRCheckBox.Value
                diffIm = fliplr(diffIm);
            end
            figure,imshow(diffIm),
        end

        % Value changed function: FlipUDCheckBox
        function FlipUDCheckBoxValueChanged(app, event)
            app.updateImage;
        end

        % Value changed function: FlipLRCheckBox
        function FlipLRCheckBoxValueChanged(app, event)
            app.updateImage;
        end

        % Value changed function: TGreenEditField
        function TGreenEditFieldValueChanged(app, event)
            value = app.TGreenEditField.Value;
            app.TGreenSlider.Value = value;
            app.updateImage;
        end

        % Value changed function: TMagentaEditField
        function TMagentaEditFieldValueChanged(app, event)
            value = app.TMagentaEditField.Value;
            app.TMagentaSlider.Value = value;
            app.updateImage;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            newVal = app.TMagentaSlider.Value - verticalScrollCount;
            newVal = max(0, min(newVal, size(app.CropImages,3)-1));
            app.TMagentaEditField.Value = newVal;
            app.TMagentaSlider.Value = newVal;
            app.updateImage;
        end

        % Button pushed function: RealignCentroidsButton
        function RealignCentroidsButtonPushed(app, event)
            disp('Re-computing Bottom Camera Alignment'),
            
            % load files
            root_dir = "C:\Users\arono\Documents\code\il_rig_control\arena_alignment\";
            load(root_dir + "arena_model.mat"),
            load(root_dir + "ref_obj.mat"),
            load(root_dir + "arena_items.mat"),
            load(root_dir + "bot_cam_intrinsics.mat")
            
            % set anchor points for alignment
            anchorCaches = [1, 2, 5, 6, 9, 10,...
                            13, 14, 21, 22,...
                            37, 38, 47, 48,...
                            63, 64, 71, 72,...
                            75, 76, 79, 80, 83, 84];
            nSites = length(anchorCaches);
            col = lines(nSites);
            
            % reload uncropped data (cap at 10 images, no need to read all?)
            baseFolder = app.BaseFolderEditField.Value;
            allImages = dir(fullfile(baseFolder,'botImages','*.tiff'));
            [~,imOrder] = sort([allImages.datenum],'ascend');
            allImages = allImages(imOrder);
            nImages = min(length(allImages),10);
            imInfo = imfinfo(fullfile(allImages(1).folder,allImages(1).name));
            w = imInfo.Width;
            h = imInfo.Height;
            refIm = zeros(h,w,nImages,'uint8');
            for i=1:nImages
                thisFile = fullfile(allImages(i).folder,allImages(i).name);
                refIm(:,:,i) = imread(thisFile);
            end
            refIm = uint8(mean(refIm,3));
            
            % Manually adjust Cache Locations
            h1=figure;
            imshow(arena_model, arena_ref),
            arenaVertices=[];
            for thisCache = 1:nSites
                thisSite = caches(anchorCaches(thisCache)).Centroid;
                h = images.roi.Point(gca,'Position',thisSite,'Color',col(thisCache,:));
                arenaVertices = cat(1,arenaVertices, h.Position);
%                 thisBox = caches(anchorCaches(thisCache)).BoundingBox;
%                 thisBox = [thisBox(1,:),thisBox(2,:)-thisBox(1,:)];
%                 h = images.roi.Rectangle(gca,'Position',thisBox,'Color',col(thisCache,:));
%                 arenaVertices = cat(1,arenaVertices, h.Vertices);
            end
            h2=figure;
            imshow(refIm),
            clear h
            for i=1:nSites
                display(i),
                h(i) = drawpoint(gca,'Color',col(i,:));
            end
            display('Waiting to Confirm Locations'),
            pause,
            imageVertices = cat(1,h.Position);
            close(h1),close(h2),
            
            % Compute Alignment w/ and w/out distortion
            cacheLoc = cat(1,caches.Centroid);
            figure,imshow(refIm),hold on,
            % no distortion
            tform = fitgeotrans(arenaVertices,imageVertices,'projective');
            [cacheLocBot(:,1), cacheLocBot(:,2)] = tform.transformPointsForward(cacheLoc(:,1), cacheLoc(:,2));
            plot(cacheLocBot(:,1),cacheLocBot(:,2),'*'),
            % w/ distortion
            pp = botCamInt.PrincipalPoint;
            fl = mean(botCamInt.FocalLength);
            imgPts_undistorted = undistortPoints(imageVertices,botCamInt);
            tform = fitgeotrans(arenaVertices,imgPts_undistorted,'projective');
            [cacheLocBot(:,1), cacheLocBot(:,2)] = tform.transformPointsForward(cacheLoc(:,1), cacheLoc(:,2));
            normPts = (cacheLocBot-pp)./fl;
            r = sqrt(sum(normPts.^2,2));
            rDistort = 1 + botCamInt.RadialDistortion(1).* (r.^2) + botCamInt.RadialDistortion(2).* (r.^4);
            cacheLocBot_distort = fl.*(normPts.*rDistort) + pp;
            plot(cacheLocBot_distort(:,1),cacheLocBot_distort(:,2),'*'),
            
            doSave = input('Accept & Save Results?');
            app.cacheLocBot = cacheLocBot_distort;
            if doSave
                % Save results
                SaveCentroidsButtonPushed(app, event);
                % Re-load images using new results
                LoadImagesButtonPushed(app, event);
            end
        end

        % Button pushed function: RefreshImagesButton
        function RefreshImagesButtonPushed(app, event)
            baseFolder = app.BaseFolderEditField.Value;
            allImages = dir(fullfile(baseFolder,'botImages','*.tiff'));
            [~,imOrder] = sort([allImages.datenum],'ascend');
            allImages = allImages(imOrder);
            nImages = length(allImages);
            nPreImages = size(app.FullImages,3);
            newImages = allImages(nPreImages+1:nImages);
            if ~isempty(newImages)
                for i=1:length(newImages)
                    thisFile = fullfile(newImages(i).folder,newImages(i).name);
                    app.FullImages(:,:,end+1) = imread(thisFile);
                end
                
                app.cropSites;
                % set up sliders
                app.TGreenSlider.Limits = [0, nImages-1];
                app.TGreenSlider.MajorTicks = 0:5:nImages-1;
                app.TGreenSlider.MinorTicks = 0:nImages-1;
                app.TMagentaSlider.Limits = [0, nImages-1];
                app.TMagentaSlider.MajorTicks = 0:5:nImages-1;
                app.TMagentaSlider.MinorTicks = 0:nImages-1;
            end           

        end

        % Value changed function: autorefreshCheckBox
        function autorefreshCheckBoxValueChanged(app, event)
            value = app.autorefreshCheckBox.Value;
            if value
                start(app.refreshTimer),
            else
                stop(app.refreshTimer),
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.refreshTimer),
                delete(app.refreshTimer),
            catch
                warning('Error stopping timer')
            end
            delete(app)
        end

        % Button pushed function: EqGreenTimeButton
        function EqGreenTimeButtonPushed(app, event)
            value = app.TMagentaSlider.Value;
            app.TGreenEditField.Value = value;
            app.TGreenEditFieldValueChanged;
            app.updateImage;
        end

        % Button pushed function: ShowVideoButton
        function ShowVideoButtonPushed(app, event)
            magentaTime = app.TMagentaSlider.Value+1; %add 1 to go from time to index
            greenTime = app.TGreenSlider.Value+1; %add 1 to go from time to index
            
            baseImage = app.CropImages(:,:,greenTime);
            allImages = app.CropImages(:,:,greenTime:magentaTime);
            vidSize = size(allImages);
            tmpVid = zeros([vidSize(1),vidSize(2),3,vidSize(3)],'uint8');
            for i=1:vidSize(3)
                tmpVid(:,:,:,i) = insertText(imfuse(baseImage,allImages(:,:,i)),[0,0],num2str(i-1+greenTime));
            end
            implay(tmpVid),
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TMagentaSliderLabel = uilabel(app.UIFigure);
            app.TMagentaSliderLabel.Text = 'T-Magenta';
            app.TMagentaSlider = uislider(app.UIFigure);
            app.TMagentaSlider.Limits = [0 60];
            app.TMagentaSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.TMagentaSlider.ValueChangedFcn = createCallbackFcn(app, @TMagentaSliderValueChanged, true);
            app.TMagentaSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.TGreenSliderLabel = uilabel(app.UIFigure);
            app.TGreenSliderLabel.Text = 'T-Green';
            app.TGreenSlider = uislider(app.UIFigure);
            app.TGreenSlider.Limits = [0 60];
            app.TGreenSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.TGreenSlider.ValueChangedFcn = createCallbackFcn(app, @TGreenSliderValueChanged, true);
            app.TGreenSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61];
            app.BaseFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BaseFolderEditField.Value = 'Z:\Isabel\data\acquisition\';
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.ResetImLimitsButton = uibutton(app.UIFigure, 'push');
            app.ResetImLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImLimitsButtonPushed, true);
            app.ResetImLimitsButton.Text = 'Reset Im Limits';
            app.SaveCentroidsButton = uibutton(app.UIFigure, 'push');
            app.SaveCentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCentroidsButtonPushed, true);
            app.SaveCentroidsButton.Text = 'Save Centroids';
            app.RealignCentroidsButton = uibutton(app.UIFigure, 'push');
            app.RealignCentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @RealignCentroidsButtonPushed, true);
            app.RealignCentroidsButton.Text = 'Realign Centroids';
            app.PlotDiffImButton = uibutton(app.UIFigure, 'push');
            app.PlotDiffImButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDiffImButtonPushed, true);
            app.PlotDiffImButton.Text = 'Plot Diff-Im';
            app.FlipUDCheckBox = uicheckbox(app.UIFigure);
            app.FlipUDCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipUDCheckBoxValueChanged, true);
            app.FlipUDCheckBox.Text = 'Flip UD';
            app.FlipUDCheckBox.Value = true;
            app.FlipLRCheckBox = uicheckbox(app.UIFigure);
            app.FlipLRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipLRCheckBoxValueChanged, true);
            app.FlipLRCheckBox.Text = 'Flip LR';
            app.TGreenEditFieldLabel = uilabel(app.UIFigure);
            app.TGreenEditFieldLabel.Text = 'T-Green';
            app.TGreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.TGreenEditField.ValueChangedFcn = createCallbackFcn(app, @TGreenEditFieldValueChanged, true);
            app.TGreenEditField.Value = 1;
            app.TMagentaEditFieldLabel = uilabel(app.UIFigure);
            app.TMagentaEditFieldLabel.Text = 'T-Magenta';
            app.TMagentaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TMagentaEditField.ValueChangedFcn = createCallbackFcn(app, @TMagentaEditFieldValueChanged, true);
            app.TMagentaEditField.Value = 1;
            app.analyzesitesButton = uibutton(app.UIFigure, 'push');
            app.analyzesitesButton.Text = '...analyze sites...';
            app.RefreshImagesButton = uibutton(app.UIFigure, 'push');
            app.RefreshImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshImagesButtonPushed, true);
            app.RefreshImagesButton.Text = 'Refresh Images';
            app.autorefreshCheckBox = uicheckbox(app.UIFigure);
            app.autorefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @autorefreshCheckBoxValueChanged, true);
            app.autorefreshCheckBox.Text = 'auto-refresh';
            app.autorefreshCheckBox.Value = true;
            app.EqGreenTimeButton = uibutton(app.UIFigure, 'push');
            app.EqGreenTimeButton.ButtonPushedFcn = createCallbackFcn(app, @EqGreenTimeButtonPushed, true);
            app.EqGreenTimeButton.Text = 'Eq. Green Time';
            app.ShowVideoButton = uibutton(app.UIFigure, 'push');
            app.ShowVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVideoButtonPushed, true);
            app.ShowVideoButton.Text = 'Show Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_bottom_camera(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end