classdef videoTriggerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartTriggersButton        matlab.ui.control.Button
        FrameTrigConnectionButton  matlab.ui.control.StateButton
        SerialFeedEditFieldLabel   matlab.ui.control.Label
        SerialFeedEditField        matlab.ui.control.EditField
        PortEditFieldLabel         matlab.ui.control.Label
        PortEditField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        sVideoTrigger % serial object
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrameTrigConnectionButton
        function FrameTrigConnectionButtonValueChanged(app, event)
            value = app.FrameTrigConnectionButton.Value;
            if value
                try
                    port = app.PortEditField.Value;
                    baudRate = 9600;
                    app.sVideoTrigger = serialport(port,baudRate);
                    readCallback = @(src,evt) (set(app.SerialFeedEditField,'Value', src.readline));
                    configureCallback(app.sVideoTrigger,'terminator',readCallback)
                catch ME
                    app.FrameTrigConnectionButton.Value = false;
                    warning('Failed to set up Serial Connection')
                    app.SerialFeedEditField.Value = ME.identifier;
                end
            end
            if ~value
                app.sVideoTrigger.delete;
                app.SerialFeedEditField.Value = 'Connection Closed';
            end            
        end

        % Button pushed function: StartTriggersButton
        function StartTriggersButtonPushed(app, event)
            if app.FrameTrigConnectionButton.Value
                app.sVideoTrigger.writeline(' '),
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartTriggersButton = uibutton(app.UIFigure, 'push');
            app.StartTriggersButton.ButtonPushedFcn = createCallbackFcn(app, @StartTriggersButtonPushed, true);
            app.StartTriggersButton.Text = 'Start Triggers';
            app.FrameTrigConnectionButton = uibutton(app.UIFigure, 'state');
            app.FrameTrigConnectionButton.ValueChangedFcn = createCallbackFcn(app, @FrameTrigConnectionButtonValueChanged, true);
            app.FrameTrigConnectionButton.Text = 'FrameTrig Connection';
            app.SerialFeedEditFieldLabel = uilabel(app.UIFigure);
            app.SerialFeedEditFieldLabel.Text = 'Serial Feed';
            app.SerialFeedEditField = uieditfield(app.UIFigure, 'text');
            app.SerialFeedEditField.Editable = 'off';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditField.Value = 'COM4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videoTriggerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end