classdef feederApp_on_circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        FeederConnectionButton    matlab.ui.control.StateButton
        SerialFeedEditFieldLabel  matlab.ui.control.Label
        SerialFeedEditField       matlab.ui.control.EditField
        PortEditFieldLabel        matlab.ui.control.Label
        PortEditField             matlab.ui.control.EditField
        ScheduleTable             matlab.ui.control.Table
        ExecuteScheduleButton     matlab.ui.control.StateButton
        ScheduleTime              matlab.ui.control.Label
        PlusButton                matlab.ui.control.Button
        MinusButton               matlab.ui.control.Button
        State4Button              matlab.ui.control.Button
        ToggleAll5Button          matlab.ui.control.Button
        CloseAll0Button           matlab.ui.control.Button
    end

    
    properties (Access = public)
        sFeeder % serial object
        scheduleTimer % timer for automatic feeder scheduling
        DefaultSchedule
    end
    
    methods (Access = private)

    end
    
    methods (Access = public)
        
        function startScheduleTimer(app)
            c = now;
            app.scheduleTimer = timer('ExecutionMode','fixedRate','Period',1,'TimerFcn',@stopwatch);
            % sort schedule data
            scheduleData = app.ScheduleTable.Data;
            [~,sOrder] = sort(scheduleData(:,1),'ascend');
            scheduleData = scheduleData(sOrder,:);
            app.ScheduleTable.Data = scheduleData;
            function stopwatch(obj,evt)
              b = now;
              t = b - c;
              app.ScheduleTime.Text = datestr(t, 'HH:MM:SS');
              % if time exceeds next entry in table, consume it!
              nowInMin = t*(24*60);
              if size(app.ScheduleTable.Data,1)>0
                  nextTime = app.ScheduleTable.Data(1,1);
                  if nowInMin>nextTime
                      newState = app.ScheduleTable.Data(1,2);
                      app.sFeeder.writeline(num2str(newState)); %write new state to serial port
                      app.ScheduleTable.Data(1,:) = []; %consume scheduled item
                  end
              end
            end
            start(app.scheduleTimer),
            app.ScheduleTime.BackgroundColor = [0.47,0.67,0.19];
        end
        
        function stopScheduleTimer(app)
            stop(app.scheduleTimer),
            delete(app.scheduleTimer),
            app.ScheduleTime.Text = 'XX:XX:XX';
            app.ScheduleTime.BackgroundColor = [0.85,0.33,0.10];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.DefaultSchedule = [0 2; 10 1; 20 2; 40 3; 50 2; 70 1; 80 2];
%             app.DefaultSchedule = [0 1; 10 0; 30 1; 35 0; 60 1; 65 0; 90 1; 95 0]; 
            app.DefaultSchedule = [10 1; 15 0; 30 1; 35 0; 60 1; 65 0; 90 1; 95 0]; 
%             app.DefaultSchedule = []; 
%             for wi = 10:30:120
%                 for si = 0:2:4
%                     app.DefaultSchedule = [app.DefaultSchedule; (wi+si) 1; (wi+si+1) 0]; 
%                 end
%             end
            app.ScheduleTable.Data = app.DefaultSchedule;
        end

        % Value changed function: FeederConnectionButton
        function FeederConnectionButtonValueChanged(app, event)
            value = app.FeederConnectionButton.Value;
            if value
                try
                    port = app.PortEditField.Value;
                    baudRate = 9600;
                    app.sFeeder = serialport(port,baudRate);
%                     readCallback = @(src,evt) (set(app.SerialFeedEditField,'Value', src.readline));
                    readCallback = @(src,evt) (readCallback_fcn(src,evt));
                    configureCallback(app.sFeeder,'terminator',readCallback)
                catch ME
                    app.FeederConnectionButton.Value = false;
                    warning('Failed to set up Serial Connection')
                    app.SerialFeedEditField.Value = ME.identifier;
                end
            end
            if ~value
                app.sFeeder.delete;
                app.SerialFeedEditField.Value = 'Connection Closed';
            end            
            function readCallback_fcn(src,evt)
                readme = src.readline; 
                set(app.SerialFeedEditField,'Value', readme);
                tmp = char(readme);
                
                % save
                store_timestamps_local = 'C:\Users\arono\Documents\IL_data\behavioral_data\test\feeder_timestamps';
                store_timestamps_remote = 'Z:\Isabel\data\feeder_timestamps';
                if length(dir(fullfile(store_timestamps_local, datestr(now, 'yymmdd'))))==0
                    mkdir(fullfile(store_timestamps_local, datestr(now, 'yymmdd')))
                end
                if length(dir(fullfile(store_timestamps_remote, datestr(now, 'yymmdd'))))==0
                    mkdir(fullfile(store_timestamps_remote, datestr(now, 'yymmdd')))
                end
                if length(tmp)>0
                    timestamp = datestr(now, 'yymmdd_HHMMSS');
                    save(fullfile(store_timestamps_local, datestr(now, 'yymmdd'), ['motor_' timestamp '_' tmp(~isspace(tmp))]), 'readme')
                    save(fullfile(store_timestamps_remote, datestr(now, 'yymmdd'), ['motor_' timestamp '_' tmp(~isspace(tmp))]), 'readme')
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('1')
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('2')
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('3')
            end
        end

        % Value changed function: ExecuteScheduleButton
        function ExecuteScheduleButtonValueChanged(app, event)
            value = app.ExecuteScheduleButton.Value;
            if value
                % check for functioning serial connection!
                if ~isempty(app.sFeeder)
                    app.startScheduleTimer,
                else
                    app.ExecuteScheduleButton.Value = 0;
                    app.SerialFeedEditField.Value = 'Missing Serialport Obj';
                end
            else
                app.stopScheduleTimer;
                app.ScheduleTable.Data = app.DefaultSchedule;
            end
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.ScheduleTable.Data = [app.ScheduleTable.Data;[inf 0]];
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            if size(app.ScheduleTable.Data,1)>0
                app.ScheduleTable.Data(end,:) = [];
            end
        end

        % Cell edit callback: ScheduleTable
        function ScheduleTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % sort schedule data
            scheduleData = app.ScheduleTable.Data;
            [~,sOrder] = sort(scheduleData(:,1),'ascend');
            scheduleData = scheduleData(sOrder,:);
            app.ScheduleTable.Data = scheduleData;
        end

        % Button pushed function: ToggleAll5Button
        function ToggleAll5ButtonPushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('5')
            end
        end

        % Button pushed function: State4Button
        function State4ButtonPushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('4')
            end
        end

        % Button pushed function: CloseAll0Button
        function CloseAll0ButtonPushed(app, event)
            if app.FeederConnectionButton.Value
                app.sFeeder.writeline('0')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.FeederConnectionButton = uibutton(app.UIFigure, 'state');
            app.FeederConnectionButton.ValueChangedFcn = createCallbackFcn(app, @FeederConnectionButtonValueChanged, true);
            app.FeederConnectionButton.Text = 'Feeder Connection';
            app.SerialFeedEditFieldLabel = uilabel(app.UIFigure);
            app.SerialFeedEditFieldLabel.Text = 'Serial Feed';
            app.SerialFeedEditField = uieditfield(app.UIFigure, 'text');
            app.SerialFeedEditField.Editable = 'off';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditField.Value = 'COM4';
            app.ScheduleTable = uitable(app.UIFigure);
            app.ScheduleTable.ColumnName = {'Time (m)'; 'State'};
            app.ScheduleTable.ColumnEditable = true;
            app.ScheduleTable.CellEditCallback = createCallbackFcn(app, @ScheduleTableCellEdit, true);
            app.ExecuteScheduleButton = uibutton(app.UIFigure, 'state');
            app.ExecuteScheduleButton.ValueChangedFcn = createCallbackFcn(app, @ExecuteScheduleButtonValueChanged, true);
            app.ExecuteScheduleButton.Text = 'Execute Schedule';
            app.ScheduleTime = uilabel(app.UIFigure);
            app.ScheduleTime.Text = 'XX:XX:XX';
            app.PlusButton = uibutton(app.UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.UIFigure, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.State4Button = uibutton(app.UIFigure, 'push');
            app.State4Button.ButtonPushedFcn = createCallbackFcn(app, @State4ButtonPushed, true);
            app.State4Button.Text = '4';
            app.ToggleAll5Button = uibutton(app.UIFigure, 'push');
            app.ToggleAll5Button.ButtonPushedFcn = createCallbackFcn(app, @ToggleAll5ButtonPushed, true);
            app.ToggleAll5Button.Text = 'Toggle All (5)';
            app.CloseAll0Button = uibutton(app.UIFigure, 'push');
            app.CloseAll0Button.ButtonPushedFcn = createCallbackFcn(app, @CloseAll0ButtonPushed, true);
            app.CloseAll0Button.Text = 'Close All (0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = feederApp_on_circle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end